classdef mandelbrotFractals < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        ImEditField          matlab.ui.control.NumericEditField
        ImEditFieldLabel     matlab.ui.control.Label
        ReEditField          matlab.ui.control.NumericEditField
        ReEditFieldLabel     matlab.ui.control.Label
        AccuracySlider       matlab.ui.control.Slider
        AccuracySliderLabel  matlab.ui.control.Label
        ZoomSlider           matlab.ui.control.Slider
        ZoomSliderLabel      matlab.ui.control.Label
        ResetgraphsButton    matlab.ui.control.Button
        SetButton            matlab.ui.control.Button
        SetcenterLabel       matlab.ui.control.Label
        AppforfractalsLabel  matlab.ui.control.Label
        UIAxes2              matlab.ui.control.UIAxes
        UIAxes               matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: ZoomSlider
        function ZoomSliderValueChanged(app, event)

            cla (app.UIAxes, 'reset')

            MAX_NUM_OF_COMPUTATIONS = 20;
            DRAW_AROUND_RE = -1.46561237;
            DRAW_AROUND_IM = 0.00002221;
            RE_MIN = -2;
            RE_MAX = 1;
            IM_MIN = -1;
            IM_MAX = 1;

            zoom = app.ZoomSlider.Value;
            step = 0.01;
            realPartPlot = DRAW_AROUND_RE+(RE_MIN-DRAW_AROUND_RE/zoom):(step/zoom):DRAW_AROUND_RE+(RE_MAX-DRAW_AROUND_RE/zoom);
            imaginaryPartPlot = DRAW_AROUND_IM+(IM_MIN/zoom):(step/zoom):DRAW_AROUND_IM+(IM_MAX/zoom);

            hold (app.UIAxes, 'on')
            %set(app.UIAxes,'color', 'blue');

            for re = realPartPlot
                for im = imaginaryPartPlot
                    c = re + im*1i;
                    deviation = mandelbrotCheck(c, MAX_NUM_OF_COMPUTATIONS);
                    if(deviation == MAX_NUM_OF_COMPUTATIONS)
                        plot(app.UIAxes, re, im, 'black.')      
                    elseif(deviation > 2*(MAX_NUM_OF_COMPUTATIONS/3) && deviation < MAX_NUM_OF_COMPUTATIONS)
                        plot(app.UIAxes, re, im, 'red.')
                    elseif(deviation > MAX_NUM_OF_COMPUTATIONS/2 && deviation < 2*(MAX_NUM_OF_COMPUTATIONS/3))
                        plot(app.UIAxes, re, im, 'magenta.')
                    elseif(deviation > MAX_NUM_OF_COMPUTATIONS/3 && deviation < MAX_NUM_OF_COMPUTATIONS/2)
                        plot(app.UIAxes, re, im, 'yellow.')
                    elseif(deviation > MAX_NUM_OF_COMPUTATIONS/8 && deviation < MAX_NUM_OF_COMPUTATIONS/3)
                        plot(app.UIAxes, re, im, 'green.')
                    elseif(deviation > MAX_NUM_OF_COMPUTATIONS/16 && deviation < MAX_NUM_OF_COMPUTATIONS/8)
                        plot(app.UIAxes, re, im, 'cyan.')
                    end
                end
            end
        end

        % Button pushed function: ResetgraphsButton
        function ResetgraphsButtonPushed(app, event)
             cla (app.UIAxes, 'reset');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, {'Mandelbrot fractals'; ''})
            xlabel(app.UIAxes, 'Re')
            ylabel(app.UIAxes, 'Im')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Julia fractals')
            xlabel(app.UIAxes2, 'Re')
            ylabel(app.UIAxes2, {'Im'; ''})
            zlabel(app.UIAxes2, 'Z')
            app.AppforfractalsLabel = uilabel(app.UIFigure);
            app.AppforfractalsLabel.Text = 'App for fractals';
            app.SetcenterLabel = uilabel(app.UIFigure);
            app.SetcenterLabel.Text = 'Set center:';
            app.SetButton = uibutton(app.UIFigure, 'push');
            app.SetButton.Text = 'Set';
            app.ResetgraphsButton = uibutton(app.UIFigure, 'push');
            app.ResetgraphsButton.ButtonPushedFcn = createCallbackFcn(app, @ResetgraphsButtonPushed, true);
            app.ResetgraphsButton.Text = 'Reset graphs';
            app.ZoomSliderLabel = uilabel(app.UIFigure);
            app.ZoomSliderLabel.Text = 'Zoom';
            app.ZoomSlider = uislider(app.UIFigure);
            app.ZoomSlider.Limits = [1 100];
            app.ZoomSlider.ValueChangedFcn = createCallbackFcn(app, @ZoomSliderValueChanged, true);
            app.ZoomSlider.Value = 1;
            app.AccuracySliderLabel = uilabel(app.UIFigure);
            app.AccuracySliderLabel.Text = 'Accuracy';
            app.AccuracySlider = uislider(app.UIFigure);
            app.AccuracySlider.Limits = [0.01 0.1];
            app.AccuracySlider.Value = 0.01;
            app.ReEditFieldLabel = uilabel(app.UIFigure);
            app.ReEditFieldLabel.Text = 'Re';
            app.ReEditField = uieditfield(app.UIFigure, 'numeric');
            app.ImEditFieldLabel = uilabel(app.UIFigure);
            app.ImEditFieldLabel.Text = 'Im';
            app.ImEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mandelbrotFractals

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end